classdef threeLinkGui_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        JointAnglesLabel          matlab.ui.control.Label
        newDHLabel                matlab.ui.control.Label
        optimizeButton            matlab.ui.control.StateButton
        d2EditField               matlab.ui.control.NumericEditField
        d2EditFieldLabel          matlab.ui.control.Label
        d1EditField               matlab.ui.control.NumericEditField
        d1EditFieldLabel          matlab.ui.control.Label
        a2EditField               matlab.ui.control.NumericEditField
        a2EditFieldLabel          matlab.ui.control.Label
        a1EditField               matlab.ui.control.NumericEditField
        a1EditFieldLabel          matlab.ui.control.Label
        EndEffectorPositionLabel  matlab.ui.control.Label
        YMinusButton              matlab.ui.control.StateButton
        YPlusButton               matlab.ui.control.StateButton
        XMinusButton              matlab.ui.control.StateButton
        XPlusButton               matlab.ui.control.StateButton
        text3                     matlab.ui.control.Label
        text2                     matlab.ui.control.Label
        Create                    matlab.ui.control.Button
        slider2                   matlab.ui.control.Slider
        slider1                   matlab.ui.control.Slider
        axes1                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot =[]; % This is a Serial Link Created robot 
        jointangles= [0 0]; % these are the joint angles of the robot
        endEffectorPos = [0 0];% these are the positons of the robot
        increment = .5 
        
    end
    
    methods (Access = public)
        
           function updateRobotPlot(app)


           app.robot.plot(app.jointangles);
            T = app.robot.fkine(app.jointangles);
             pos = T.t';
             app.endEffectorPos=pos;
            updateEndEffectorPositionLabel(app)
           end

        function updateEndEffectorPositionLabel(app)
           app.robot.plot(app.jointangles);
            app.EndEffectorPositionLabel.Text = sprintf('End-Effector Position: (%.2f, %.2f,)', ...
                app.endEffectorPos(1), app.endEffectorPos(2));
              app.JointAnglesLabel.Text = sprintf('Joint Angles: (%.2f, %.2f,)', ...
                rad2deg(app.jointangles(1)), rad2deg(app.jointangles(2)));
        end


        function err = func3(app,test)
        err= 0;
        for i=1:5
            q = test(1:2)';  % Joint angles;
            %Forward kinematics
            T =fkine(app.robot,q);
            pos = T.t;       % model  coordinates
            d = test(3:4);    % Measured coordinates
            % distance of real measurements
            Real = sqrt((test(3)-0).^2 + (test(4)-0).^2);
        
            %distance of model measurements
            Model = sqrt((pos(1)-0).^2 + (pos(2)-0).^2);
            % find the error of real measurements and model measurements
            each_err = sqrt(sum((Real-Model).^2));
            err=err + each_err;

           % app.jointangles = q';
           %  updateEndEffectorPositionLabel(app)
        end  
        %app.robot.plot(q)
        err = err/5
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Create, a1EditField, a2EditField, d1EditField,
        % 
        % ...and 1 other component
        function Create_Callback(app, event)
            %create a simple two link robot.
L(1) = Link ('d',app.d1EditField.Value, 'a',app.a1EditField.Value, 'alpha', 0,'offset',deg2rad(18.435));
 L(2) = Link ('d', app.d2EditField.Value, 'a',app.a2EditField.Value, 'alpha', 0,'offset',deg2rad(19));
            app.robot = SerialLink(L, 'name', 'threeLinkRobot');
            app.robot.base = [0 -11.5 6.5]; % base offset y =-11.5 and z= 6.5 cm
            app.robot.plot (app.jointangles); %plotting initial joint angles
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            %get the values of the sliders from each joint
            app.jointangles(1) = app.slider1.Value;
            app.jointangles(2) = app.slider2.Value;

           app.updateRobotPlot();
           
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            app.jointangles(1) = app.slider1.Value;
            app.jointangles(2) = app.slider2.Value;
         
           app.updateRobotPlot();

        end

        % Value changed function: XMinusButton, XPlusButton, YMinusButton, 
        % ...and 1 other component
        function Button(app, event)
          
                if app.XPlusButton.Value
                    app.endEffectorPos(1) = app.endEffectorPos(1) + app.increment;
                    app.XPlusButton.Value=false; % returns the button to zero state
                
            elseif app.XMinusButton.Value
                    app.endEffectorPos(1) = app.endEffectorPos(1) - app.increment;
                    app.XMinusButton.Value=false; % returns the button to zero state


            elseif app.YPlusButton.Value
                    app.endEffectorPos(2) = app.endEffectorPos(2) + app.increment;
                    app.YPlusButton.Value=false; % returns the button to zero state

            elseif app.YMinusButton.Value
                    app.endEffectorPos(2) = app.endEffectorPos(2) - app.increment;
                    app.YMinusButton.Value=false; % returns the button to zero state

                end

            % U take endEffectorPos and put into a 4x4 matrix
            U = [-0 -1 0 app.endEffectorPos(1);1 -0 0 app.endEffectorPos(2);0 1 1 0;0 0 0 1]; 
           %  inverse kinematics
            G = app.robot.ikunc(U); % takes endEffectorPos to and find jointangles
            
            app.jointangles = G; % places G into   app.jointangles 
             updateEndEffectorPositionLabel(app) % send to funtion 


        end

        % Value changed function: optimizeButton
        function optimizeButtonValueChanged(app, event)
          if app.optimizeButton.Value
    
       C = [app.d1EditField.Value app.d2EditField.Value app.a1EditField.Value app.a2EditField.Value]; % puts old DH parameters in array

    %  fid = fopen('dataRad.txt', 'r'); % open files
    fid = fopen('dataRad.txt', 'r'); % open files
      s = fgets(fid); % get Data
      test = sscanf(s, '%f %f %f %f\n'); % puts data into a matrix 

            % Call fminsearch with function handle
            output = fminsearch(@(test) app.func3(test), C);
            % prints out outpus in to gui 
            app.newDHLabel.Text = sprintf('New Dh: (d1 %.2f, d2 %.2f,a1 %.2f, a2 %.2f)', output(1),output(2), output(3), output(4) );
             app.optimizeButton.Value=false; % returns the button to zero state
          end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'twolink';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 3.14159265358979];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [0 3.14159265358979];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.Create = uibutton(app.figure1, 'push');
            app.Create.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.Create.Tag = 'Create';
            app.Create.Text = 'Create Robot';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Joint 1';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Joint 2';
            app.XPlusButton = uibutton(app.figure1, 'state');
            app.XPlusButton.ValueChangedFcn = createCallbackFcn(app, @Button, true);
            app.XPlusButton.Text = 'XPlus';
            app.XMinusButton = uibutton(app.figure1, 'state');
            app.XMinusButton.ValueChangedFcn = createCallbackFcn(app, @Button, true);
            app.XMinusButton.Text = 'XMinus';
            app.YPlusButton = uibutton(app.figure1, 'state');
            app.YPlusButton.ValueChangedFcn = createCallbackFcn(app, @Button, true);
            app.YPlusButton.Text = 'YPlus';
            app.YMinusButton = uibutton(app.figure1, 'state');
            app.YMinusButton.ValueChangedFcn = createCallbackFcn(app, @Button, true);
            app.YMinusButton.Text = 'YMinus';
            app.EndEffectorPositionLabel = uilabel(app.figure1);
            app.EndEffectorPositionLabel.Text = 'EndEffectorPosition';
            app.a1EditFieldLabel = uilabel(app.figure1);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.figure1, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.a1EditField.Value = 10;
            app.a2EditFieldLabel = uilabel(app.figure1);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.figure1, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.a2EditField.Value = 11;
            app.d1EditFieldLabel = uilabel(app.figure1);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.figure1, 'numeric');
            app.d1EditField.ValueChangedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.d1EditField.Value = 2;
            app.d2EditFieldLabel = uilabel(app.figure1);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.figure1, 'numeric');
            app.d2EditField.ValueChangedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.d2EditField.Value = 0.6;
            app.optimizeButton = uibutton(app.figure1, 'state');
            app.optimizeButton.ValueChangedFcn = createCallbackFcn(app, @optimizeButtonValueChanged, true);
            app.optimizeButton.Text = 'optimize ';
            app.newDHLabel = uilabel(app.figure1);
            app.newDHLabel.Text = 'new DH';
            app.JointAnglesLabel = uilabel(app.figure1);
            app.JointAnglesLabel.Text = 'Joint Angles';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threeLinkGui_final

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end